classdef MWSegEval_Toolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PresstoRunButton                matlab.ui.control.StateButton
        MWSegEvalimageanalysistoolboxLabel  matlab.ui.control.Label
        TaskCompletedInidcation         matlab.ui.control.EditField
        FEMCSIAdiposeMagThresholdIndication  matlab.ui.control.NumericEditField
        FEMCSITransitionEprThresholdIndication  matlab.ui.control.NumericEditField
        ContourExtractionThresholdIndication  matlab.ui.control.NumericEditField
        FEMCSIAdiposeEprThresholdIndication  matlab.ui.control.NumericEditField
        FEMCSITumorThresholdIndication  matlab.ui.control.NumericEditField
        SegmentationthresholdsLabel     matlab.ui.control.Label
        FormattosavefiguresButtonGroup  matlab.ui.container.ButtonGroup
        epsButton                       matlab.ui.control.RadioButton
        pngButton                       matlab.ui.control.RadioButton
        figButton                       matlab.ui.control.RadioButton
        DonotSaveButton                 matlab.ui.control.RadioButton
        FEMCSIAdiposeImagThresholdIndication  matlab.ui.control.NumericEditField
        ColormaptodisplayfiguresButtonGroup  matlab.ui.container.ButtonGroup
        jetButton                       matlab.ui.control.RadioButton
        parulaButton                    matlab.ui.control.RadioButton
        grayButton                      matlab.ui.control.RadioButton
        FEMCSITransitionImagThresholdIndication  matlab.ui.control.NumericEditField
        FEMCSITransitionMagThresholdIndication  matlab.ui.control.NumericEditField
        TasklistListBoxLabel            matlab.ui.control.Label
        TasklistListBox                 matlab.ui.control.ListBox
        ForwardandReconstructionModelsdatapathEditFieldLabel  matlab.ui.control.Label
        ForwardandReconstructionModelsdatapathEditField  matlab.ui.control.EditField
        ContourextractionthresholdLabel  matlab.ui.control.Label
        ContourextractionthresholdKnob  matlab.ui.control.Knob
        TransitionReEpsLabel            matlab.ui.control.Label
        FEMCSITransitionEprmaxthresholdKnob  matlab.ui.control.Knob
        AdiposeMagEpsLabel              matlab.ui.control.Label
        FEMCSIAdiposeMagmaxthresholdKnob  matlab.ui.control.Knob
        TumorReImandMagofMaxLabel       matlab.ui.control.Label
        FEMCSITumorEprmaxthresholdKnob  matlab.ui.control.Knob
        AdiposeReEpsLabel_2             matlab.ui.control.Label
        FEMCSIAdiposeEprmaxthresholdKnob  matlab.ui.control.Knob
        AdiposeImgEpsLabel              matlab.ui.control.Label
        FEMCSIAdiposeImagmaxthresholdKnob  matlab.ui.control.Knob
        SegmentationuseAutomaticSwitchLabel  matlab.ui.control.Label
        SegmentationuseAutomaticSwitch  matlab.ui.control.ToggleSwitch
        SkinThicknessOffsetKnobLabel    matlab.ui.control.Label
        SkinThicknessOffsetKnob         matlab.ui.control.Knob
        TransitionImEpsLabel            matlab.ui.control.Label
        FEMCSITransitionImagmaxthresholdKnob  matlab.ui.control.Knob
        TransitionMagEpsLabel           matlab.ui.control.Label
        FEMCSITransitionMagmaxthresholdKnob  matlab.ui.control.Knob
        ContourthresholdsLabel          matlab.ui.control.Label
        ThresholdinitializedwhenTransformimagetaskisperformedLabel  matlab.ui.control.Label
        SkinThicknessThresholdIndication  matlab.ui.control.NumericEditField
        NumberofcontoursSpinnerLabel    matlab.ui.control.Label
        NumberofcontoursSpinner         matlab.ui.control.Spinner
        InputmodelformatSwitchLabel     matlab.ui.control.Label
        InputmodelformatSwitch          matlab.ui.control.ToggleSwitch
        SelectcontrastiterationfromlistListBoxLabel  matlab.ui.control.Label
        SelectcontrastiterationfromlistListBox  matlab.ui.control.ListBox
        PresstogetcontrastiterationfilelistButton  matlab.ui.control.Button
        AdjustmentknobsdisabledwhileLabel  matlab.ui.control.Label
        AutomaticsegmentationinuseLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ContourextractionthresholdKnob
        function ContourextractionthresholdKnobValueChanged(app, event)
            app.ContourExtractionThresholdIndication.Value = app.ContourextractionthresholdKnob.Value;            
        end

        % Value changed function: FEMCSIAdiposeEprmaxthresholdKnob
        function FEMCSIAdiposeEprmaxthresholdKnobValueChanged(app, event)
            app.FEMCSIAdiposeEprThresholdIndication.Value = app.FEMCSIAdiposeEprmaxthresholdKnob.Value;            
        end

        % Value changed function: FEMCSIAdiposeImagmaxthresholdKnob
        function FEMCSIAdiposeImagmaxthresholdKnobValueChanged(app, event)
            app.FEMCSIAdiposeImagThresholdIndication.Value = app.FEMCSIAdiposeImagmaxthresholdKnob.Value;
        end

        % Value changed function: FEMCSITumorEprmaxthresholdKnob
        function FEMCSITumorEprmaxthresholdKnobValueChanged(app, event)
            app.FEMCSITumorThresholdIndication.Value = app.FEMCSITumorEprmaxthresholdKnob.Value;            
        end

        % Value changed function: FEMCSIAdiposeMagmaxthresholdKnob
        function FEMCSIAdiposeMagmaxthresholdKnobValueChanged(app, event)
            app.FEMCSIAdiposeMagThresholdIndication.Value = app.FEMCSIAdiposeMagmaxthresholdKnob.Value;            
        end

        % Value changed function: 
        % FEMCSITransitionEprmaxthresholdKnob
        function FEMCSITransitionEprmaxthresholdKnobValueChanged(app, event)
            app.FEMCSITransitionEprThresholdIndication.Value = app.FEMCSITransitionEprmaxthresholdKnob.Value;            
        end

        % Value changed function: PresstoRunButton
        function PresstoRunButtonValueChanged(app, event)
            app.PresstoRunButton.BackgroundColor = [1 1 0.2];
            app.PresstoRunButton.FontColor = [0.15 0.15 0];
            app.PresstoRunButton.Text = 'Running';
            app.TaskCompletedInidcation.Value = app.TasklistListBox.Value;  
            pause(1)
            
            if strcmp(app.InputmodelformatSwitch.Value, 'Triangular mesh')
                if isempty(app.SelectcontrastiterationfromlistListBox.Value)
                    app.PresstoRunButton.BackgroundColor = [0 1 0];
                    app.PresstoRunButton.FontColor = [0.15 0.15 0];
                    app.PresstoRunButton.Text = 'Press to Run';
                    app.TaskCompletedInidcation.Value = 'Error: contrast iteration file not selected';                
                    return           
                end
                femCsiIn.contrastIteration = str2num(app.SelectcontrastiterationfromlistListBox.Value);                
            else
                femCsiIn.contrastIteration = 0;
            end
                
            
            contourAnalysisIn.MaxNmCntr = app.NumberofcontoursSpinner.Value;
            contourAnalysisIn.CntrTh = app.ContourextractionthresholdKnob.Value;
            segmentationThresholdIn.skinThkOffset = app.SkinThicknessOffsetKnob.Value;
            
            segmentationThresholdIn.FEMCSIrealAdipose = app.FEMCSIAdiposeEprmaxthresholdKnob.Value;            
            segmentationThresholdIn.FEMCSIimAdipose = app.FEMCSIAdiposeImagmaxthresholdKnob.Value;
            segmentationThresholdIn.FEMCSImagAdipose = app.FEMCSIAdiposeMagmaxthresholdKnob.Value;
            
            segmentationThresholdIn.FEMCSIrealTransition = app.FEMCSITransitionEprmaxthresholdKnob.Value;            
            segmentationThresholdIn.FEMCSIimTransition = app.FEMCSITransitionImagmaxthresholdKnob.Value;
            segmentationThresholdIn.FEMCSImagTransition = app.FEMCSITransitionMagmaxthresholdKnob.Value;
            
            segmentationThresholdIn.FEMCSITumorMaxThreshold =  app.FEMCSITumorEprmaxthresholdKnob.Value;
                        
            segmentationThresholdIn.SegmentationuseAutomatic = app.SegmentationuseAutomaticSwitch.Value;
            
            segmentationThresholdIn.FwdEprAdipose = app.FEMCSIAdiposeMagmaxthresholdKnob.Value;            
            segmentationThresholdIn.FwdEprTumor =  app.FEMCSITransitionEprmaxthresholdKnob.Value;              
            
            fileAndFigProp.inputModelFormat = app.InputmodelformatSwitch.Value;                                  
            fileAndFigProp.datapath = app.ForwardandReconstructionModelsdatapathEditField.Value;
            fileAndFigProp.epsFileSave = app.epsButton.Value;
            fileAndFigProp.pngFileSave = app.pngButton.Value;
            fileAndFigProp.figFileSave = app.figButton.Value;            
            fileAndFigProp.jetColorMap = app.jetButton.Value;
            fileAndFigProp.parulaColorMap = app.parulaButton.Value;
            fileAndFigProp.grayColorMap = app.grayButton.Value;
            fileAndFigProp.doNotSave = app.DonotSaveButton.Value;
            
            gui = taskManager( app.TasklistListBox.Value, femCsiIn, contourAnalysisIn, segmentationThresholdIn, fileAndFigProp);                                        
            app.TaskCompletedInidcation.Value = gui.taskStatus;
            if strcmp(extractBetween(gui.taskStatus,1,5),'Error' )
                app.PresstoRunButton.FontColor = [0.15 0.15 0];
                app.PresstoRunButton.BackgroundColor = [0 1 0];                
                app.PresstoRunButton.Text = 'Press to Run';
                app.TaskCompletedInidcation.Value = gui.taskStatus;
                return                
            end
            app.PresstoRunButton.BackgroundColor = [0 1 0];
            app.PresstoRunButton.FontColor = [0.15 0.15 0];
            app.PresstoRunButton.Text = 'Press to Run';     
            if strcmp(app.TasklistListBox.Value, 'Input forward and reconstructed models for processing')                                
                if strcmp(extractBetween(gui.taskStatus,1,5),'Error' )                    
                    app.PresstoRunButton.BackgroundColor = [0 1 0];
                    app.PresstoRunButton.FontColor = [0.15 0.15 0];
                    app.PresstoRunButton.Text = 'Press to Run';
                    app.TaskCompletedInidcation.Value = gui.taskStatus;
                    return
                end                
                app.TaskCompletedInidcation.Value = 'Initializing thresholds';
                app.FEMCSIAdiposeEprmaxthresholdKnob.Value = gui.segmenationIniValues.femcsi_fat_epr_Hi_Limit;
                app.FEMCSIAdiposeEprThresholdIndication.Value = gui.segmenationIniValues.femcsi_fat_epr_Hi_Limit;                        
            
                app.FEMCSIAdiposeImagmaxthresholdKnob.Value = gui.segmenationIniValues.femcsi_fat_imag_Hi_Limit;
                app.FEMCSIAdiposeImagThresholdIndication.Value = gui.segmenationIniValues.femcsi_fat_imag_Hi_Limit;
            
                app.FEMCSIAdiposeMagmaxthresholdKnob.Value = gui.segmenationIniValues.femcsi_fat_mag_Hi_Limit;
                app.FEMCSIAdiposeMagThresholdIndication.Value = gui.segmenationIniValues.femcsi_fat_mag_Hi_Limit;
            
                app.FEMCSITransitionEprmaxthresholdKnob.Value = gui.segmenationIniValues.femcsi_gland_epr_Lo_Limit;
                app.FEMCSITransitionEprThresholdIndication.Value = gui.segmenationIniValues.femcsi_gland_epr_Lo_Limit;
            
                app.FEMCSITransitionImagmaxthresholdKnob.Value = gui.segmenationIniValues.femcsi_gland_imag_Lo_Limit;
                app.FEMCSITransitionImagThresholdIndication.Value = gui.segmenationIniValues.femcsi_gland_imag_Lo_Limit;            
            
                app.FEMCSITransitionMagmaxthresholdKnob.Value = gui.segmenationIniValues.femcsi_gland_mag_Lo_Limit;
                app.FEMCSITransitionMagThresholdIndication.Value = gui.segmenationIniValues.femcsi_gland_mag_Lo_Limit;            
            
                app.FEMCSITumorEprmaxthresholdKnob.Value = gui.segmenationIniValues.femcsi_tumor_epr_Lo_Limit;
                app.FEMCSITumorThresholdIndication.Value = gui.segmenationIniValues.femcsi_tumor_epr_Lo_Limit;
            
                app.SkinThicknessOffsetKnob.Value = 3.5;   
            
                app.NumberofcontoursSpinner.Value = 10;                            
                app.ContourExtractionThresholdIndication.Value = app.ContourextractionthresholdKnob.Value;                                
                if strcmp(app.SegmentationuseAutomaticSwitch.Value, 'Yes')
                    app.SegmentationuseAutomaticSwitch.Value = 'Yes';
                else
                    app.SegmentationuseAutomaticSwitch.Value = 'No';                     
                end                
                app.TaskCompletedInidcation.Value = gui.taskStatus;
            end
        end

        % Callback function
        function FEMCSITransitionEprmaxthresholdKnobValueChanging(app, event)
            app.FEMCSITransitionEprThresholdIndication.Value = app.FEMCSITransitionEprmaxthresholdKnob.Value;
            
        end

        % Value changed function: 
        % FEMCSITransitionImagThresholdIndication, 
        % FEMCSITransitionImagmaxthresholdKnob
        function FEMCSITransitionImagThresholdIndicationValueChanged(app, event)
            app.FEMCSITransitionImagThresholdIndication.Value = app.FEMCSITransitionImagmaxthresholdKnob.Value;            
        end

        % Value changed function: 
        % FEMCSITransitionMagThresholdIndication, 
        % FEMCSITransitionMagmaxthresholdKnob
        function FEMCSITransitionMagThresholdIndicationValueChanged(app, event)
            app.FEMCSITransitionMagThresholdIndication.Value = app.FEMCSITransitionMagmaxthresholdKnob.Value;            
        end

        % Value changed function: SkinThicknessOffsetKnob, 
        % SkinThicknessThresholdIndication
        function SkinThicknessKnobChanged(app, event)
            app.SkinThicknessThresholdIndication.Value = app.SkinThicknessOffsetKnob.Value;            
        end

        % Value changed function: InputmodelformatSwitch
        function InputmodelformatSwitchValueChanged(app, event)
            if strcmp(app.InputmodelformatSwitch.Value, 'Rectangular mesh (Matlab)')                              
                app.PresstogetcontrastiterationfilelistButton.Visible = 'off';                
                app.SelectcontrastiterationfromlistListBox.Visible = 'off';
                app.SelectcontrastiterationfromlistListBoxLabel.Visible = 'off';                
            else                
                app.PresstogetcontrastiterationfilelistButton.Visible = 'on';                
                app.SelectcontrastiterationfromlistListBox.Visible = 'on';
                app.SelectcontrastiterationfromlistListBoxLabel.Visible = 'on';                
            end
                
        end

        % Button pushed function: 
        % PresstogetcontrastiterationfilelistButton
        function PresstogetcontrastiterationfilelistButtonPushed(app, event)
            FEMCSIdatapath = app.ForwardandReconstructionModelsdatapathEditField.Value;
            if ~isdir(FEMCSIdatapath)                
                app.PresstoRunButton.BackgroundColor = [0 1 0];
                app.PresstoRunButton.FontColor = [0.15 0.15 0];
                app.PresstoRunButton.Text = 'Press to Run';
                app.TaskCompletedInidcation.Value = 'Error: Invalid forward and reconstruction models data path';                              
                return
            else                
                if ~exist([FEMCSIdatapath '\data'],'dir')                    
                    app.PresstoRunButton.BackgroundColor = [0 1 0];
                    app.PresstoRunButton.FontColor = [0.15 0.15 0];
                    app.PresstoRunButton.Text = 'Press to Run';
                    app.TaskCompletedInidcation.Value = 'Error: no inversion data. Check for correct data path.';
                    return
                end
            end
            app.PresstoRunButton.BackgroundColor = [0 1 0];
            app.PresstoRunButton.FontColor = [0.15 0.15 0];
            app.PresstoRunButton.Text = 'Getting list';
            app.TaskCompletedInidcation.Value = 'Getting contrast iteration file list';
            pause(1)
            
            datafolder = dir([FEMCSIdatapath '\data']);
            i = 1;
            flag = 1;
            while(flag)
                ans = strfind(datafolder(i).name, 'Contrast');
                if isempty(ans)
                    i = i + 1;
                end
                if ans == 1
                    flag = 0;
                end
            end
            contrastArray = [];
            flag = 1;            
            errorFlag = 0;
            j = 1;
            k = 1;            
            while(flag)
                try
                    ans = strfind(datafolder(i).name, 'Contrast');                    
                catch
                    flag = 0;
                    errorFlag = 1;
                end                
                if isempty(ans) || errorFlag
                    flag = 0;                    
                else
                    contrast = datafolder(i).name;                
                    contrastArray(k) = str2num(string((extractBetween(contrast, 9, strfind(contrast, '.')-1))));
                    j = j + 1;
                    i = i + 1;
                    k = k + 1;
                end
            end
            contrastArray = sort(contrastArray);
            for i = 1:length(contrastArray)
                app.SelectcontrastiterationfromlistListBox.Items(i) = cellstr(num2str(contrastArray(i)));                
            end
            app.PresstoRunButton.BackgroundColor = [0 1 0];
            app.PresstoRunButton.FontColor = [0.15 0.15 0];
            app.PresstoRunButton.Text = 'Press to Run';
            app.TaskCompletedInidcation.Value = 'Select contrast iteration file from list.';
        end

        % Value changed function: SegmentationuseAutomaticSwitch
        function SegmentationuseAutomaticSwitchValueChanged(app, event)
            
            if strcmp(app.SegmentationuseAutomaticSwitch.Value, 'Yes')                              
                app.FEMCSIAdiposeEprmaxthresholdKnob.Visible = 'off';
                app.FEMCSIAdiposeEprThresholdIndication.Visible = 'off';
                app.AdiposeReEpsLabel_2.Visible = 'off';
                app.FEMCSIAdiposeImagmaxthresholdKnob.Visible = 'off';
                app.FEMCSIAdiposeImagThresholdIndication.Visible = 'off';
                app.AdiposeImgEpsLabel.Visible = 'off';
                app.FEMCSIAdiposeMagmaxthresholdKnob.Visible = 'off';
                app.FEMCSIAdiposeMagThresholdIndication.Visible = 'off';
                app.AdiposeMagEpsLabel.Visible = 'off';
                app.FEMCSITransitionEprmaxthresholdKnob.Visible = 'off';
                app.FEMCSITransitionEprThresholdIndication.Visible = 'off';
                app.TransitionReEpsLabel.Visible = 'off';
                app.FEMCSITransitionImagmaxthresholdKnob.Visible = 'off';
                app.FEMCSITransitionImagThresholdIndication.Visible = 'off';
                app.TransitionImEpsLabel.Visible = 'off';
                app.FEMCSITransitionMagmaxthresholdKnob.Visible = 'off';
                app.FEMCSITransitionMagThresholdIndication.Visible = 'off';
                app.TransitionMagEpsLabel.Visible = 'off';
                app.FEMCSITumorEprmaxthresholdKnob.Visible = 'off';
                app.FEMCSITumorThresholdIndication.Visible = 'off';
                app.TumorReImandMagofMaxLabel.Visible = 'off';
                app.AdjustmentknobsdisabledwhileLabel.Visible = 'on';
                app.AutomaticsegmentationinuseLabel.Visible = 'on';
                
            else                
                app.FEMCSIAdiposeEprmaxthresholdKnob.Visible = 'on';
                app.FEMCSIAdiposeEprThresholdIndication.Visible = 'on';
                app.AdiposeReEpsLabel_2.Visible = 'on';
                app.FEMCSIAdiposeImagmaxthresholdKnob.Visible = 'on';
                app.FEMCSIAdiposeImagThresholdIndication.Visible = 'on';
                app.AdiposeImgEpsLabel.Visible = 'on';
                app.FEMCSIAdiposeMagmaxthresholdKnob.Visible = 'on';
                app.FEMCSIAdiposeMagThresholdIndication.Visible = 'on';
                app.AdiposeMagEpsLabel.Visible = 'on';
                app.FEMCSITransitionEprmaxthresholdKnob.Visible = 'on';
                app.FEMCSITransitionEprThresholdIndication.Visible = 'on';
                app.TransitionReEpsLabel.Visible = 'on';
                app.FEMCSITransitionImagmaxthresholdKnob.Visible = 'on';
                app.FEMCSITransitionImagThresholdIndication.Visible = 'on';
                app.TransitionImEpsLabel.Visible = 'on';
                app.FEMCSITransitionMagmaxthresholdKnob.Visible = 'on';
                app.FEMCSITransitionMagThresholdIndication.Visible = 'on';
                app.TransitionMagEpsLabel.Visible = 'on';
                app.FEMCSITumorEprmaxthresholdKnob.Visible = 'on';
                app.FEMCSITumorThresholdIndication.Visible = 'on';
                app.TumorReImandMagofMaxLabel.Visible = 'on'; 
                app.AdjustmentknobsdisabledwhileLabel.Visible = 'off';
                app.AutomaticsegmentationinuseLabel.Visible = 'off';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PresstoRunButton = uibutton(app.UIFigure, 'state');
            app.PresstoRunButton.ValueChangedFcn = createCallbackFcn(app, @PresstoRunButtonValueChanged, true);
            app.PresstoRunButton.Text = 'Press to Run';
            app.MWSegEvalimageanalysistoolboxLabel = uilabel(app.UIFigure);
            app.MWSegEvalimageanalysistoolboxLabel.Text = 'MWSegEval - image analysis toolbox';
            app.TaskCompletedInidcation = uieditfield(app.UIFigure, 'text');
            app.TaskCompletedInidcation.Value = 'Set forward and reconstruction models data path';
            app.FEMCSIAdiposeMagThresholdIndication = uieditfield(app.UIFigure, 'numeric');
            app.FEMCSIAdiposeMagThresholdIndication.Editable = 'off';
            app.FEMCSITransitionEprThresholdIndication = uieditfield(app.UIFigure, 'numeric');
            app.FEMCSITransitionEprThresholdIndication.ValueDisplayFormat = '%.2f';
            app.FEMCSITransitionEprThresholdIndication.Editable = 'off';
            app.ContourExtractionThresholdIndication = uieditfield(app.UIFigure, 'numeric');
            app.ContourExtractionThresholdIndication.Limits = [0 30];
            app.ContourExtractionThresholdIndication.ValueDisplayFormat = '%11.3g';
            app.ContourExtractionThresholdIndication.Editable = 'off';
            app.ContourExtractionThresholdIndication.Value = 10;
            app.FEMCSIAdiposeEprThresholdIndication = uieditfield(app.UIFigure, 'numeric');
            app.FEMCSIAdiposeEprThresholdIndication.Editable = 'off';
            app.FEMCSITumorThresholdIndication = uieditfield(app.UIFigure, 'numeric');
            app.FEMCSITumorThresholdIndication.Editable = 'off';
            app.SegmentationthresholdsLabel = uilabel(app.UIFigure);
            app.SegmentationthresholdsLabel.Text = 'Segmentation thresholds';
            app.FormattosavefiguresButtonGroup = uibuttongroup(app.UIFigure);
            app.FormattosavefiguresButtonGroup.Title = 'Format to save figures';
            app.epsButton = uiradiobutton(app.FormattosavefiguresButtonGroup);
            app.epsButton.Text = 'eps';
            app.pngButton = uiradiobutton(app.FormattosavefiguresButtonGroup);
            app.pngButton.Text = 'png';
            app.figButton = uiradiobutton(app.FormattosavefiguresButtonGroup);
            app.figButton.Text = 'fig';
            app.DonotSaveButton = uiradiobutton(app.FormattosavefiguresButtonGroup);
            app.DonotSaveButton.Text = 'Do not Save';
            app.DonotSaveButton.Value = true;
            app.FEMCSIAdiposeImagThresholdIndication = uieditfield(app.UIFigure, 'numeric');
            app.FEMCSIAdiposeImagThresholdIndication.ValueDisplayFormat = '%.2f';
            app.FEMCSIAdiposeImagThresholdIndication.Editable = 'off';
            app.ColormaptodisplayfiguresButtonGroup = uibuttongroup(app.UIFigure);
            app.ColormaptodisplayfiguresButtonGroup.Title = 'Color map to display figures';
            app.jetButton = uiradiobutton(app.ColormaptodisplayfiguresButtonGroup);
            app.jetButton.Text = 'jet';
            app.jetButton.Value = true;
            app.parulaButton = uiradiobutton(app.ColormaptodisplayfiguresButtonGroup);
            app.parulaButton.Text = 'parula';
            app.grayButton = uiradiobutton(app.ColormaptodisplayfiguresButtonGroup);
            app.grayButton.Text = 'gray';
            app.FEMCSITransitionImagThresholdIndication = uieditfield(app.UIFigure, 'numeric');
            app.FEMCSITransitionImagThresholdIndication.ValueDisplayFormat = '%.2f';
            app.FEMCSITransitionImagThresholdIndication.ValueChangedFcn = createCallbackFcn(app, @FEMCSITransitionImagThresholdIndicationValueChanged, true);
            app.FEMCSITransitionImagThresholdIndication.Editable = 'off';
            app.FEMCSITransitionMagThresholdIndication = uieditfield(app.UIFigure, 'numeric');
            app.FEMCSITransitionMagThresholdIndication.ValueDisplayFormat = '%.2f';
            app.FEMCSITransitionMagThresholdIndication.ValueChangedFcn = createCallbackFcn(app, @FEMCSITransitionMagThresholdIndicationValueChanged, true);
            app.FEMCSITransitionMagThresholdIndication.Editable = 'off';
            app.TasklistListBoxLabel = uilabel(app.UIFigure);
            app.TasklistListBoxLabel.Text = 'Task list';
            app.TasklistListBox = uilistbox(app.UIFigure);
            app.TasklistListBox.Items = {'Input forward and reconstructed models for processing', 'Segment forward and reconstructed models into tissue masks', 'Analyze tissue interfaces', 'Analyze geometric properties of reconstructed tissues', 'Analyze dielectric properties of tissues within reconstructed masks', 'Output csv file of metric values for each region'};
            app.TasklistListBox.Value = 'Input forward and reconstructed models for processing';
            app.ForwardandReconstructionModelsdatapathEditFieldLabel = uilabel(app.UIFigure);
            app.ForwardandReconstructionModelsdatapathEditFieldLabel.Text = 'Forward and Reconstruction Models data path';
            app.ForwardandReconstructionModelsdatapathEditField = uieditfield(app.UIFigure, 'text');
            app.ContourextractionthresholdLabel = uilabel(app.UIFigure);
            app.ContourextractionthresholdLabel.Text = 'Contour extraction threshold %';
            app.ContourextractionthresholdKnob = uiknob(app.UIFigure, 'continuous');
            app.ContourextractionthresholdKnob.Limits = [0 30];
            app.ContourextractionthresholdKnob.ValueChangedFcn = createCallbackFcn(app, @ContourextractionthresholdKnobValueChanged, true);
            app.ContourextractionthresholdKnob.Value = 10;
            app.TransitionReEpsLabel = uilabel(app.UIFigure);
            app.TransitionReEpsLabel.Text = 'Transition Re(Eps)';
            app.FEMCSITransitionEprmaxthresholdKnob = uiknob(app.UIFigure, 'continuous');
            app.FEMCSITransitionEprmaxthresholdKnob.Limits = [25 40];
            app.FEMCSITransitionEprmaxthresholdKnob.ValueChangedFcn = createCallbackFcn(app, @FEMCSITransitionEprmaxthresholdKnobValueChanged, true);
            app.FEMCSITransitionEprmaxthresholdKnob.Value = 25;
            app.AdiposeMagEpsLabel = uilabel(app.UIFigure);
            app.AdiposeMagEpsLabel.Text = 'Adipose Mag(Eps) ';
            app.FEMCSIAdiposeMagmaxthresholdKnob = uiknob(app.UIFigure, 'continuous');
            app.FEMCSIAdiposeMagmaxthresholdKnob.Limits = [5 16];
            app.FEMCSIAdiposeMagmaxthresholdKnob.ValueChangedFcn = createCallbackFcn(app, @FEMCSIAdiposeMagmaxthresholdKnobValueChanged, true);
            app.FEMCSIAdiposeMagmaxthresholdKnob.Value = 5;
            app.TumorReImandMagofMaxLabel = uilabel(app.UIFigure);
            app.TumorReImandMagofMaxLabel.Text = 'Tumor Re, Im, and Mag (% of Max)';
            app.FEMCSITumorEprmaxthresholdKnob = uiknob(app.UIFigure, 'continuous');
            app.FEMCSITumorEprmaxthresholdKnob.Limits = [75 100];
            app.FEMCSITumorEprmaxthresholdKnob.ValueChangedFcn = createCallbackFcn(app, @FEMCSITumorEprmaxthresholdKnobValueChanged, true);
            app.FEMCSITumorEprmaxthresholdKnob.Value = 90;
            app.AdiposeReEpsLabel_2 = uilabel(app.UIFigure);
            app.AdiposeReEpsLabel_2.Text = 'Adipose Re(Eps)';
            app.FEMCSIAdiposeEprmaxthresholdKnob = uiknob(app.UIFigure, 'continuous');
            app.FEMCSIAdiposeEprmaxthresholdKnob.Limits = [5 15];
            app.FEMCSIAdiposeEprmaxthresholdKnob.ValueChangedFcn = createCallbackFcn(app, @FEMCSIAdiposeEprmaxthresholdKnobValueChanged, true);
            app.FEMCSIAdiposeEprmaxthresholdKnob.Value = 5;
            app.AdiposeImgEpsLabel = uilabel(app.UIFigure);
            app.AdiposeImgEpsLabel.Text = 'Adipose Img(Eps)';
            app.FEMCSIAdiposeImagmaxthresholdKnob = uiknob(app.UIFigure, 'continuous');
            app.FEMCSIAdiposeImagmaxthresholdKnob.Limits = [1.1 3.5];
            app.FEMCSIAdiposeImagmaxthresholdKnob.ValueChangedFcn = createCallbackFcn(app, @FEMCSIAdiposeImagmaxthresholdKnobValueChanged, true);
            app.FEMCSIAdiposeImagmaxthresholdKnob.Value = 1.1;
            app.SegmentationuseAutomaticSwitchLabel = uilabel(app.UIFigure);
            app.SegmentationuseAutomaticSwitchLabel.Text = 'Segmentation - use Automatic';
            app.SegmentationuseAutomaticSwitch = uiswitch(app.UIFigure, 'toggle');
            app.SegmentationuseAutomaticSwitch.Items = {'No', 'Yes'};
            app.SegmentationuseAutomaticSwitch.ValueChangedFcn = createCallbackFcn(app, @SegmentationuseAutomaticSwitchValueChanged, true);
            app.SegmentationuseAutomaticSwitch.Value = 'No';
            app.SkinThicknessOffsetKnobLabel = uilabel(app.UIFigure);
            app.SkinThicknessOffsetKnobLabel.Text = 'Skin Thickness Offset';
            app.SkinThicknessOffsetKnob = uiknob(app.UIFigure, 'continuous');
            app.SkinThicknessOffsetKnob.Limits = [0 20];
            app.SkinThicknessOffsetKnob.ValueChangedFcn = createCallbackFcn(app, @SkinThicknessKnobChanged, true);
            app.SkinThicknessOffsetKnob.Value = 3.5;
            app.TransitionImEpsLabel = uilabel(app.UIFigure);
            app.TransitionImEpsLabel.Text = 'Transition Im(Eps)';
            app.FEMCSITransitionImagmaxthresholdKnob = uiknob(app.UIFigure, 'continuous');
            app.FEMCSITransitionImagmaxthresholdKnob.Limits = [8 20];
            app.FEMCSITransitionImagmaxthresholdKnob.ValueChangedFcn = createCallbackFcn(app, @FEMCSITransitionImagThresholdIndicationValueChanged, true);
            app.FEMCSITransitionImagmaxthresholdKnob.Value = 8;
            app.TransitionMagEpsLabel = uilabel(app.UIFigure);
            app.TransitionMagEpsLabel.Text = 'Transition Mag(Eps)';
            app.FEMCSITransitionMagmaxthresholdKnob = uiknob(app.UIFigure, 'continuous');
            app.FEMCSITransitionMagmaxthresholdKnob.Limits = [25 40];
            app.FEMCSITransitionMagmaxthresholdKnob.ValueChangedFcn = createCallbackFcn(app, @FEMCSITransitionMagThresholdIndicationValueChanged, true);
            app.FEMCSITransitionMagmaxthresholdKnob.Value = 25;
            app.ContourthresholdsLabel = uilabel(app.UIFigure);
            app.ContourthresholdsLabel.Text = 'Contour thresholds';
            app.ThresholdinitializedwhenTransformimagetaskisperformedLabel = uilabel(app.UIFigure);
            app.ThresholdinitializedwhenTransformimagetaskisperformedLabel.Text = 'Threshold automatically initialized when ''Input models'' task is performed';
            app.SkinThicknessThresholdIndication = uieditfield(app.UIFigure, 'numeric');
            app.SkinThicknessThresholdIndication.Limits = [0 20];
            app.SkinThicknessThresholdIndication.ValueChangedFcn = createCallbackFcn(app, @SkinThicknessKnobChanged, true);
            app.SkinThicknessThresholdIndication.Value = 3.5;
            app.NumberofcontoursSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofcontoursSpinnerLabel.Text = 'Number of contours';
            app.NumberofcontoursSpinner = uispinner(app.UIFigure);
            app.NumberofcontoursSpinner.Limits = [1 20];
            app.NumberofcontoursSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofcontoursSpinner.Value = 10;
            app.InputmodelformatSwitchLabel = uilabel(app.UIFigure);
            app.InputmodelformatSwitchLabel.Text = 'Input model format';
            app.InputmodelformatSwitch = uiswitch(app.UIFigure, 'toggle');
            app.InputmodelformatSwitch.Items = {'Rectangular mesh (Matlab)', 'Triangular mesh'};
            app.InputmodelformatSwitch.ValueChangedFcn = createCallbackFcn(app, @InputmodelformatSwitchValueChanged, true);
            app.InputmodelformatSwitch.Value = 'Rectangular mesh (Matlab)';
            app.SelectcontrastiterationfromlistListBoxLabel = uilabel(app.UIFigure);
            app.SelectcontrastiterationfromlistListBoxLabel.Visible = 'off';
            app.SelectcontrastiterationfromlistListBoxLabel.Text = {'Select contrast'; 'iteration from list'};
            app.SelectcontrastiterationfromlistListBox = uilistbox(app.UIFigure);
            app.SelectcontrastiterationfromlistListBox.Items = {};
            app.SelectcontrastiterationfromlistListBox.Visible = 'off';
            app.SelectcontrastiterationfromlistListBox.Value = {};
            app.PresstogetcontrastiterationfilelistButton = uibutton(app.UIFigure, 'push');
            app.PresstogetcontrastiterationfilelistButton.ButtonPushedFcn = createCallbackFcn(app, @PresstogetcontrastiterationfilelistButtonPushed, true);
            app.PresstogetcontrastiterationfilelistButton.Visible = 'off';
            app.PresstogetcontrastiterationfilelistButton.Text = {'Press to get contrast'; 'iteration file list'};
            app.AdjustmentknobsdisabledwhileLabel = uilabel(app.UIFigure);
            app.AdjustmentknobsdisabledwhileLabel.Visible = 'off';
            app.AdjustmentknobsdisabledwhileLabel.Text = 'Adjustment knobs disabled while';
            app.AutomaticsegmentationinuseLabel = uilabel(app.UIFigure);
            app.AutomaticsegmentationinuseLabel.Visible = 'off';
            app.AutomaticsegmentationinuseLabel.Text = 'Automatic segmentation in use';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MWSegEval_Toolbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end